classdef Exp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PulseEditField       matlab.ui.control.EditField
        PulseEditFieldLabel  matlab.ui.control.Label
        AFLamp               matlab.ui.control.Lamp
        AFLampLabel          matlab.ui.control.Label
        startButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: UIAxes, startButton
        function plotButtonPushed(app, event)
            load liveECG.mat
            load trainedModelEnsemble.mat
            x=(0:1:length(liveECG)-1)/300;
            y=liveECG;
            for i=50:25:length(y)
                s=i-5000;
                if s<1
                    s=1;
                end
                plot(app.UIAxes,x(s:i),y(s:i),'k','linewidth',2)
                pause(1/250)
                xlim(app.UIAxes,[x(i)-3 x(i)+0.5])
                ylim(app.UIAxes,[-5 10])
                drawnow


                Fs = 300;
                [~,max_freq,mean_P_wave_freq] = FFT_nolable( y(s:i) ,0,Fs);
                threshold=0.008*(max(y(s:i)));
                win_length=10;
                QRS_detect=PanThomp_QRSs(y(s:i),win_length,Fs,threshold,0);
                inx = find(QRS_detect);
                scatter(app.UIAxes,x(inx+s),y(inx+s),140,'*','r','linewidth',1)
                drawnow
                [meanRR,BPM,Var_RR] = features(QRS_detect,Fs);
                FramedSig=enframe(y(s:i),128);
                spectral_centroid = spec_centroid(FramedSig,Fs);
                curr_mat = [max_freq mean_P_wave_freq meanRR BPM  Var_RR spectral_centroid];
                curr_table = array2table(curr_mat,'VariableNames',...
                    {'max_freq','mean_P_wave_freq','meanRR','BPM','Var_RR','spectral_centroid'});

                yfit = trainedModelEnsamble.predictFcn(curr_table); 
                app.PulseEditField.Value = num2str(round(BPM));
                if yfit == 0
                    app.AFLamp.Color = 'g';
                else
                    app.AFLamp.Color = 'r';
                end
            end
        end

        % Callback function
        function StatusBarSizeChanged(app, event)

        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: PulseEditField
        function PulseEditFieldValueChanged(app, event)
            value = app.PulseEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'live ECG')
            xlabel(app.UIAxes, 'Time [sec]')
            ylabel(app.UIAxes, 'Voltage [mV]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.startButton.Text = 'start';
            app.AFLampLabel = uilabel(app.UIFigure);
            app.AFLampLabel.Text = 'AF';
            app.AFLamp = uilamp(app.UIFigure);
            app.PulseEditFieldLabel = uilabel(app.UIFigure);
            app.PulseEditFieldLabel.Text = 'Pulse';
            app.PulseEditField = uieditfield(app.UIFigure, 'text');
            app.PulseEditField.ValueChangedFcn = createCallbackFcn(app, @PulseEditFieldValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exp4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end